classdef GGV_Laptime < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GES                             matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        CarsettingsTab                  matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        MainTab                         matlab.ui.container.Tab
        MassesanddimensionsPanel        matlab.ui.container.Panel
        kgLabel_3                       matlab.ui.control.Label
        MassEditField_3Label            matlab.ui.control.Label
        mtot                            matlab.ui.control.NumericEditField
        Label_3                         matlab.ui.control.Label
        MassdistribonrearEditField_2Label  matlab.ui.control.Label
        Massdistrib                     matlab.ui.control.NumericEditField
        WheelbaseEditField_2Label       matlab.ui.control.Label
        WheelbaseEditField_2            matlab.ui.control.NumericEditField
        mLabel_10                       matlab.ui.control.Label
        mLabel_11                       matlab.ui.control.Label
        ReartrackwidthEditField_2Label  matlab.ui.control.Label
        ReartrackwidthEditField_2       matlab.ui.control.NumericEditField
        FronttrackwidthEditField_2Label  matlab.ui.control.Label
        FronttrackwidthEditField_2      matlab.ui.control.NumericEditField
        mLabel_12                       matlab.ui.control.Label
        COGheightEditField_2Label       matlab.ui.control.Label
        COGheightEditField_2            matlab.ui.control.NumericEditField
        mLabel_13                       matlab.ui.control.Label
        AerodynamicsPanel               matlab.ui.container.Panel
        Label                           matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        mLabel_14                       matlab.ui.control.Label
        SEditFieldLabel                 matlab.ui.control.Label
        SEditField                      matlab.ui.control.NumericEditField
        CzdistribonrearEditFieldLabel   matlab.ui.control.Label
        CzdistribonrearEditField        matlab.ui.control.NumericEditField
        Label_5                         matlab.ui.control.Label
        CxEditField_2Label              matlab.ui.control.Label
        CxEditField_2                   matlab.ui.control.NumericEditField
        CzEditFieldLabel                matlab.ui.control.Label
        CzEditField                     matlab.ui.control.NumericEditField
        BrakesPanel_2                   matlab.ui.container.Panel
        ms2Label_2                      matlab.ui.control.Label
        Deccelmax50kmhEditFieldLabel    matlab.ui.control.Label
        Deccelmax50kmhEditField         matlab.ui.control.NumericEditField
        FronttorquedistributionEditFieldLabel  matlab.ui.control.Label
        FronttorquedistributionEditField  matlab.ui.control.NumericEditField
        PilotePanel                     matlab.ui.container.Panel
        mLabel_15                       matlab.ui.control.Label
        COGheightEditField_3Label       matlab.ui.control.Label
        COGheightEditField_3            matlab.ui.control.NumericEditField
        kgLabel_4                       matlab.ui.control.Label
        MassEditField_4Label            matlab.ui.control.Label
        MassEditField_4                 matlab.ui.control.NumericEditField
        LoadsettingsButton              matlab.ui.control.Button
        LoadexistingcarsettingsfromamatfileLabel  matlab.ui.control.Label
        SavesettingsButton              matlab.ui.control.Button
        SavecurrentallsettingsinamatfileLabel  matlab.ui.control.Label
        Type                            matlab.ui.control.Switch
        Image                           matlab.ui.control.Image
        Versiondu24052020DemanderBobencasdebugLabel  matlab.ui.control.Label
        EngineTab                       matlab.ui.container.Tab
        PlotTorquePowercurbButton       matlab.ui.control.Button
        RPMMaxEditField_2Label          matlab.ui.control.Label
        RPMMaxEditField_2               matlab.ui.control.NumericEditField
        rpmLabel_3                      matlab.ui.control.Label
        SlippingRPMrgimedepatinageEditFieldLabel  matlab.ui.control.Label
        SlippingRPMrgimedepatinageEditField  matlab.ui.control.NumericEditField
        rpmLabel_4                      matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        SlippingRPMrgimedepatinageEditFieldLabel_2  matlab.ui.control.Label
        PlotMotorEfficiencycurbButton   matlab.ui.control.Button
        ControllerefficiencyEditFieldLabel  matlab.ui.control.Label
        ControllerefficiencyEditField   matlab.ui.control.NumericEditField
        EngineAxe                       matlab.ui.control.UIAxes
        GearboxTab                      matlab.ui.container.Tab
        GearshifttimeEditFieldLabel     matlab.ui.control.Label
        GearshifttimeEditField          matlab.ui.control.NumericEditField
        sLabel                          matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        TransmissionlosscoeffEditField  matlab.ui.control.NumericEditField
        TransmissionlosscoeffEditFieldLabel  matlab.ui.control.Label
        RatiosPanel                     matlab.ui.container.Panel
        Label_7                         matlab.ui.control.Label
        PrimaryratioEditField           matlab.ui.control.NumericEditField
        PrimaryratioEditFieldLabel      matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        stgearEditField                 matlab.ui.control.NumericEditField
        stgearEditFieldLabel            matlab.ui.control.Label
        Label_9                         matlab.ui.control.Label
        ndgearEditField                 matlab.ui.control.NumericEditField
        ndgearEditFieldLabel            matlab.ui.control.Label
        Label_10                        matlab.ui.control.Label
        thgearEditField                 matlab.ui.control.NumericEditField
        thgearEditFieldLabel            matlab.ui.control.Label
        Label_11                        matlab.ui.control.Label
        thgearEditField_2               matlab.ui.control.NumericEditField
        thgearEditField_2Label          matlab.ui.control.Label
        Label_12                        matlab.ui.control.Label
        thgearEditField_3               matlab.ui.control.NumericEditField
        thgearEditField_3Label          matlab.ui.control.Label
        thgearEditField_4               matlab.ui.control.NumericEditField
        thgearLabel                     matlab.ui.control.Label
        FinalratioEditField             matlab.ui.control.NumericEditField
        FinalratioEditFieldLabel        matlab.ui.control.Label
        InertiasPanel                   matlab.ui.container.Panel
        Label_13                        matlab.ui.control.Label
        RimandtireEditField             matlab.ui.control.NumericEditField
        RimandtireEditFieldLabel        matlab.ui.control.Label
        Label_14                        matlab.ui.control.Label
        WheelhubandbrakediskEditField   matlab.ui.control.NumericEditField
        WheelhubandbrakediskEditFieldLabel  matlab.ui.control.Label
        Label_15                        matlab.ui.control.Label
        DifferentialEditField           matlab.ui.control.NumericEditField
        DifferentialEditFieldLabel      matlab.ui.control.Label
        Label_16                        matlab.ui.control.Label
        EngineandchainEditField         matlab.ui.control.NumericEditField
        EngineandchainEditFieldLabel    matlab.ui.control.Label
        Label_17                        matlab.ui.control.Label
        Label_18                        matlab.ui.control.Label
        kgmLabel                        matlab.ui.control.Label
        kgmLabel_2                      matlab.ui.control.Label
        kgmLabel_3                      matlab.ui.control.Label
        kgmLabel_4                      matlab.ui.control.Label
        kgmLabel_5                      matlab.ui.control.Label
        DriveshaftEditField             matlab.ui.control.NumericEditField
        DriveshaftEditFieldLabel        matlab.ui.control.Label
        EngineandchainEditField_2       matlab.ui.control.NumericEditField
        EngineandchainEditFieldLabel_2  matlab.ui.control.Label
        kgmLabel_6                      matlab.ui.control.Label
        UITable2                        matlab.ui.control.Table
        BestRPMtoGearshiftLabel         matlab.ui.control.Label
        ComputeButton                   matlab.ui.control.Button
        FinalratioEditField_2           matlab.ui.control.NumericEditField
        FinalratioEditField_2Label      matlab.ui.control.Label
        GearAxe                         matlab.ui.control.UIAxes
        TiresTab                        matlab.ui.container.Tab
        Label_19                        matlab.ui.control.Label
        TyrerollingresistancecoefficientEditField  matlab.ui.control.NumericEditField
        TyrerollingresistancecoefficientEditFieldLabel  matlab.ui.control.Label
        Label_20                        matlab.ui.control.Label
        LongitudinaletiregripEditField  matlab.ui.control.NumericEditField
        LongitudinaletiregripEditFieldLabel  matlab.ui.control.Label
        mLabel_16                       matlab.ui.control.Label
        WheelDiameterEditField          matlab.ui.control.NumericEditField
        WheelDiameterEditFieldLabel     matlab.ui.control.Label
        PlotButton                      matlab.ui.control.Button
        LateralcoeffcientsLabel         matlab.ui.control.Label
        p_Cy1EditFieldLabel             matlab.ui.control.Label
        p_Cy1EditField                  matlab.ui.control.NumericEditField
        p_Dy1EditFieldLabel             matlab.ui.control.Label
        p_Dy1EditField                  matlab.ui.control.NumericEditField
        p_Ky1EditFieldLabel             matlab.ui.control.Label
        p_Ky1EditField                  matlab.ui.control.NumericEditField
        ShapefactorforCfyLabel          matlab.ui.control.Label
        LateralfrictionMuyLabel         matlab.ui.control.Label
        MaximumvalueofstiffnessKfyFznomLabel  matlab.ui.control.Label
        BenchtoroadcoefficientEditField  matlab.ui.control.NumericEditField
        BenchtoroadcoefficientEditField_2Label  matlab.ui.control.Label
        Label_21                        matlab.ui.control.Label
        p_Ey1EditFieldLabel             matlab.ui.control.Label
        p_Ey1EditField                  matlab.ui.control.NumericEditField
        Label_22                        matlab.ui.control.Label
        p_Ey3EditFieldLabel             matlab.ui.control.Label
        p_Ey3EditField                  matlab.ui.control.NumericEditField
        Label_23                        matlab.ui.control.Label
        p_Hy1EditFieldLabel             matlab.ui.control.Label
        p_Hy1EditField                  matlab.ui.control.NumericEditField
        Label_24                        matlab.ui.control.Label
        p_Vy1EditFieldLabel             matlab.ui.control.Label
        p_Vy1EditField                  matlab.ui.control.NumericEditField
        Label_25                        matlab.ui.control.Label
        ZoEditFieldLabel                matlab.ui.control.Label
        ZoEditField                     matlab.ui.control.NumericEditField
        TireAxe                         matlab.ui.control.UIAxes
        ImporttrackTab                  matlab.ui.container.Tab
        StartSliderLabel                matlab.ui.control.Label
        StartSlider                     matlab.ui.control.Slider
        EndSliderLabel                  matlab.ui.control.Label
        EndSlider                       matlab.ui.control.Slider
        ImportfrommatButton             matlab.ui.control.Button
        ImportfromworkspaceButton       matlab.ui.control.Button
        SaveTrackButton                 matlab.ui.control.Button
        ImportdatafromLabel             matlab.ui.control.Label
        aprevioussessionLabel           matlab.ui.control.Label
        presentinworkspaceLabel         matlab.ui.control.Label
        VariableXandYmustbeLabel        matlab.ui.control.Label
        amatfileLabel                   matlab.ui.control.Label
        SavethetrackinLabel             matlab.ui.control.Label
        BesttimesPanel                  matlab.ui.container.Panel
        AccelEditFieldLabel             matlab.ui.control.Label
        AccelEditField                  matlab.ui.control.NumericEditField
        SkidpadEditFieldLabel           matlab.ui.control.Label
        SkidpadEditField                matlab.ui.control.NumericEditField
        AutoXEditFieldLabel             matlab.ui.control.Label
        AutoXEditField                  matlab.ui.control.NumericEditField
        sLabel_2                        matlab.ui.control.Label
        sLabel_3                        matlab.ui.control.Label
        sLabel_4                        matlab.ui.control.Label
        TrackAxe                        matlab.ui.control.UIAxes
        SimulationTab                   matlab.ui.container.Tab
        CarsettingsfilePanel            matlab.ui.container.Panel
        BrowseButton                    matlab.ui.control.Button
        FilenameLabel                   matlab.ui.control.Label
        TrackfilePanel                  matlab.ui.container.Panel
        BrowseButton_2                  matlab.ui.control.Button
        FilenameLabel_2                 matlab.ui.control.Label
        SimulateButton                  matlab.ui.control.Button
        ComputingLabel                  matlab.ui.control.Label
        ResultsPanel                    matlab.ui.container.Panel
        AccelTimeLabel                  matlab.ui.control.Label
        SkidpadTimeLabel                matlab.ui.control.Label
        AutoXTimeLabel                  matlab.ui.control.Label
        GyLabel                         matlab.ui.control.Label
        TopSpeedLabel                   matlab.ui.control.Label
        TopSpeedLabel_2                 matlab.ui.control.Label
        PointsLabel                     matlab.ui.control.Label
        PointsLabel_2                   matlab.ui.control.Label
        PointsLabel_3                   matlab.ui.control.Label
        PlotSettingsPanel               matlab.ui.container.Panel
        PlottypeDropDownLabel           matlab.ui.control.Label
        PlottypeDropDown                matlab.ui.control.DropDown
        SpeedCheckBox                   matlab.ui.control.CheckBox
        GlateralCheckBox                matlab.ui.control.CheckBox
        GlongitudinalCheckBox           matlab.ui.control.CheckBox
        ExportDatatoworkspaceButton     matlab.ui.control.Button
        ResultAxe                       matlab.ui.control.UIAxes
        PostProcessTab                  matlab.ui.container.Tab
        TabGroup3                       matlab.ui.container.TabGroup
        TSTab                           matlab.ui.container.Tab
        LaunchTScalculsButton           matlab.ui.control.Button
        brakeenergyrecoveryCheckBox     matlab.ui.control.CheckBox
        NumberofenduranceLapEditFieldLabel  matlab.ui.control.Label
        NumberofenduranceLapEditField   matlab.ui.control.NumericEditField
        TotalenergyusedbythebatteryLabel  matlab.ui.control.Label
        PlotLabel                       matlab.ui.control.Label
        PlotDropDown                    matlab.ui.control.DropDown
        ExportDatatoworkspaceButton_2   matlab.ui.control.Button
        TSAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        param_file %path to the car param_file
        track_file %path to the track file
        Track
        AccelTime
        SkidpadTime
        AutoXTime
        VautoX
        GxautoX
        GyautoX
        TautoX
        VautoX2
        GxautoX2
        GyautoX2
        TautoX2
        DautoX
        GGV
        Sefficiency
        PbatautoX
        c_m_autoX
        rpm_autoX
    end
    
    methods (Access = private)
        function dC = Coupleij(~,rmot,cmot,k_p,k_f,K,i,j,W)
            rmot1 = W/k_p/K(i)/k_f;
            rmot2 = W/k_p/K(j)/k_f;
            dC = interp1(rmot,cmot,rmot1)/k_p/K(i)/k_f-interp1(rmot,cmot,rmot2)/k_p/K(j)/k_f;
        end
        function plotGGV(app,GGV)
            Gxmax = GGV(:,1);
            Gxmin = GGV(:,2);
            Gy = GGV(:,3);
            V = GGV(:,4);
            P = [];
            g=9.81;
            for i=1:length(V)
                th=0:0.1:pi;
                X = Gy(i)*cos(th)/g;
                Y1 = Gxmax(i)*sin(th)/g;
                Y2 = Gxmin(i)*sin(th)/g;
                P = [P;X' Y1' X'*0+V(i)*3.6;-X' Y2' X'*0+V(i)*3.6];
            end
            
            z = zeros(size(P(:,1)'));
            col = P(:,3)';  % This is the color, vary with x in this case.
            surface(app.ResultAxe,[P(:,1)';P(:,1)'],[P(:,2)';P(:,2)'],[z;z],[col;col],'facecol','no','edgecol','interp','linew',2);
            c = colorbar(app.ResultAxe);
            title(app.ResultAxe,'GGV diagramme')
            xlabel(app.ResultAxe,'Lateral accereration(g)')
            ylabel(app.ResultAxe,'Longitudinale accereration(g)')
            c.Label.String = 'Speed (km/h)';
        end
        function [] = track_plot(app, X, Y, col,Label,axe)
            % X et Y sont des coordonnées venant de lat_longi2X_Y
            % speed est en km/h
            cla(axe,'reset') % reset le graph
            z = zeros(size(X'));
            surface(axe, [X';X'],[Y';Y'],[z;z],[col';col'],'facecol','no','edgecol','interp','linew',2);
            c = colorbar(axe);
            title(axe,'Track plot')
            xlabel(axe, 'X (m)')
            ylabel(axe, 'Y (m)')
            c.Label.String = Label;
            app.Label.Text = Label;
            axis(axe,'equal');
            %set(axe,'Visible','off')
        end
        
        function [] = majType(app, Type)
            if strcmp(Type,"Combustion")
                app.RatiosPanel.Visible = 'on';
                app.UITable2.Visible = 'on';
                app.GearshifttimeEditField.Visible = 'on';
                app.sLabel.Visible = 'on';
                app.SlippingRPMrgimedepatinageEditField.Visible = 'on';
                app.rpmLabel_4.Visible = 'on';
                app.BestRPMtoGearshiftLabel.Visible = 'on';
                app.FinalratioEditField_2.Visible = 'off';
                app.PlotMotorEfficiencycurbButton.Visible = 'off';
                app.ControllerefficiencyEditField.Visible = 'off';
            else
                app.RatiosPanel.Visible = 'off';
                app.UITable2.Visible = 'off';
                app.GearshifttimeEditField.Visible = 'off';
                app.sLabel.Visible = 'off';
                app.SlippingRPMrgimedepatinageEditField.Visible = 'off';
                app.rpmLabel_4.Visible = 'off';
                app.BestRPMtoGearshiftLabel.Visible = 'off';
                app.FinalratioEditField_2.Visible = 'on';
                app.PlotMotorEfficiencycurbButton.Visible = 'on';
                app.ControllerefficiencyEditField.Visible = 'on';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            title(app.EngineAxe,'Engine Curbs')
            title(app.TireAxe,'Grip coefficients')
            title(app.GearAxe,'Wheel torque for each gear')
            title(app.TrackAxe,'Track plot')
            app.StartSlider.Visible = 'off';
            app.EndSlider.Visible = 'off';
            app.ResultsPanel.Visible = 'off';
            app.PlotSettingsPanel.Visible = 'off';
            app.ResultAxe.Visible = 'off';
            app.ComputingLabel.Visible = 'off';
            app.ExportDatatoworkspaceButton.Visible = 'off';
            app.FinalratioEditField_2.Visible = 'off';
            app.TSAxes.Visible = 'off';
            app.PlotDropDown.Visible = 'off';
            app.ExportDatatoworkspaceButton_2.Visible = 'off';
            app.TotalenergyusedbythebatteryLabel.Visible = 'off';
            
        end

        % Button pushed function: PlotTorquePowercurbButton
        function PlotTorquePowercurbButtonPushed(app, event)
            cla(app.EngineAxe)
            t = app.UITable.DisplayData; 
            RPM = t(2:end,1);
            Cm = t(2:end,2);
            lmax = max(length(RPM),length(Cm));
            yyaxis(app.EngineAxe,'left') 
            plot(app.EngineAxe,RPM(1:lmax),2*pi/60*0.001341022*RPM(1:lmax).*Cm(1:lmax))
            hold(app.EngineAxe,'on')
            ylabel(app.EngineAxe,'Power (hp)')
            yyaxis(app.EngineAxe,'right')
            plot(app.EngineAxe,RPM(1:lmax),Cm(1:lmax))
            ylabel(app.EngineAxe,'Torque (N.m)')
            xlabel(app.EngineAxe,'RPM')
            
        end

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
            cla(app.GearAxe)
            t = app.UITable.DisplayData;       
            rmot = t(:,1);
            cmot = t(:,2);
            D_roue = app.WheelDiameterEditField.Value;
            value = app.Type.Value;
            if strcmp(value,"Combustion")
                hold(app.GearAxe,'on')
                primaire = app.PrimaryratioEditField.Value;
                final = app.FinalratioEditField.Value;
                K(1) = app.stgearEditField.Value;
                K(2) = app.ndgearEditField.Value;
                K(3) = app.thgearEditField.Value;
                K(4) = app.thgearEditField_2.Value;
                K(5) = app.thgearEditField_3.Value;
                K(6) = app.thgearEditField_4.Value;
                
                for gear=1:6
                    if K(gear)~=0
                        plot(app.GearAxe,rmot*2*pi/60*primaire*K(gear)*final*D_roue/2*3.6,cmot/primaire/K(gear)/final,'DisplayName',num2str(gear))
                    end
                end
                title(app.GearAxe,'Wheel torque for each gear')
                xlabel(app.GearAxe,'Speed (km/h)')
                ylabel(app.GearAxe,'Torque (N.m)')
                legend(app.GearAxe)
                hold(app.GearAxe,'off')
                fun12 = @(X) Coupleij(app,rmot,cmot,primaire,final,K,1,2,X);
                fun23 = @(X) Coupleij(app,rmot,cmot,primaire,final,K,2,3,X);
                fun34 = @(X) Coupleij(app,rmot,cmot,primaire,final,K,3,4,X);
                fun45 = @(X) Coupleij(app,rmot,cmot,primaire,final,K,4,5,X);
                fun56 = @(X) Coupleij(app,rmot,cmot,primaire,final,K,5,6,X);
                app.UITable2.Data(1:5,1) = 2:6;
                k_p = primaire;
                k_f = final;
                app.UITable2.Data(1:5,2) = [fsolve(fun12,500)/k_p/K(1)/k_f, fsolve(fun23,1000)/k_p/K(2)/k_f, fsolve(fun34,1200)/k_p/K(3)/k_f, fsolve(fun45,1200)/k_p/K(4)/k_f, fsolve(fun56,1500)/k_p/K(5)/k_f ];
            else
                final = app.FinalratioEditField_2.Value;
                plot(app.GearAxe,rmot*2*pi/60*final*D_roue/2*3.6,cmot/final)
                xlabel(app.GearAxe,'Speed (km/h)')
                ylabel(app.GearAxe,'Torque (N.m)')
            end
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            p_Cy1 = app.p_Cy1EditField.Value;
            p_Dy1 = app.p_Dy1EditField.Value;
            p_Ky1 = app.p_Ky1EditField.Value;
            q = app.BenchtoroadcoefficientEditField.Value;
            Z = 0:500:3000;
            SA = 4:4:20;
            [Y] = simplifiedMFy(Z,SA*pi/180, p_Cy1, p_Dy1, p_Ky1, q);
            for i=1:length(SA)
                Fy = Y(i,:);
                tau = Fy./Z;
                plot(app.TireAxe,Z,tau,"DisplayName",cat(2,'Lateral coefficient SA = ',num2str(SA(i)),'°'))
                hold(app.TireAxe,'on')
            end
            mu = tau*0 + app.LongitudinaletiregripEditField.Value;
            hold(app.TireAxe,'on')
            plot(app.TireAxe,Z,mu,"DisplayName","Longitudinal coefficient")
            legend(app.TireAxe)
            title(app.TireAxe,'Grip coefficients')
            xlabel(app.TireAxe,'F_z (N)')
            hold(app.TireAxe,'off')
        end

        % Button pushed function: BrowseButton, LoadsettingsButton
        function LoadsettingsButtonPushed(app, event)
            [file,path] =  uigetfile('*.mat','Select a parameter file');
            figure(app.GES);
            if file ~=0
                app.FilenameLabel.Text = cat(2,'file name : ',file);
                app.param_file = fullfile(path,file);
                load(app.param_file)
                % --Main-- 
                
                app.Type.Value = type_engine;
                majType(app, type_engine)
                % masses and geometry
                app.mtot.Value = m_v;
                app.Massdistrib.Value = rep;
                app.WheelbaseEditField_2.Value = W;
                app.ReartrackwidthEditField_2.Value = Tr;
                app.FronttrackwidthEditField_2.Value = Tf;
                app.COGheightEditField_2.Value = h_v;
                % Aero
                app.CzEditField.Value = Cz;
                app.CxEditField_2.Value = Cx;
                app.SEditField.Value = S;
                app.CzdistribonrearEditField.Value = Cz_rep;
                % Brakes 
                app.Deccelmax50kmhEditField.Value = abs((-(m_t*g+1/2*rho*S*Cz*(50/3.6).^2)*Long_tire_grip_brake-1/2*rho*S*Cx*(50/3.6).^2)/m_t);
                app.FronttorquedistributionEditField.Value = x_brf;
                % Pilote
                app.COGheightEditField_3.Value = h_p;
                app.MassEditField_4.Value = m_p;
                % -- Engine --
                app.RPMMaxEditField_2.Value = r_rupteur;
                if strcmp(type_engine,"Combustion")
                    app.SlippingRPMrgimedepatinageEditField.Value = r_pat;
                else
                    app.Sefficiency = efficiency;
                    app.ControllerefficiencyEditField.Value = eff_cont;
                end
                app.UITable.Data(1:length(rmot),1) = rmot;
                app.UITable.Data(1:length(cmot),2) = cmot;
                % -- Gearbox -- 
                app.TransmissionlosscoeffEditField.Value = pertes;
                %Ratios
                if strcmp(type_engine,"Combustion")
                    app.GearshifttimeEditField.Value = t_pas;
                    app.PrimaryratioEditField.Value = k_p;
                    app.stgearEditField.Value = K(1);
                    app.ndgearEditField.Value = K(2);
                    app.thgearEditField.Value = K(3);
                    app.thgearEditField_2.Value = K(4);
                    app.thgearEditField_3.Value = K(5);
                    app.thgearEditField_4.Value = K(6);
                    app.FinalratioEditField.Value = k_f;
                else
                    app.FinalratioEditField_2.Value = k_f;
                end
                %Inertia
                app.RimandtireEditField.Value = Jjp;
                app.WheelhubandbrakediskEditField.Value = Jm;
                app.DriveshaftEditField.Value = Ja;
                app.DifferentialEditField.Value = Jd;
                app.EngineandchainEditField_2.Value = Jc;
                app.EngineandchainEditField.Value = Jmot;
                
                % -- Tires --
                app.WheelDiameterEditField.Value = D_wheel;
                app.TyrerollingresistancecoefficientEditField.Value = coeff_roul;
                app.LongitudinaletiregripEditField.Value = Long_tire_grip;
                app.BenchtoroadcoefficientEditField.Value = q;
                app.p_Cy1EditField.Value = p_Cy1;
                app.p_Dy1EditField.Value = p_Dy1;
                app.p_Ky1EditField.Value = p_Ky1;
                app.p_Ey1EditField.Value = p_Ey1;
                app.p_Ey3EditField.Value = p_Ey3;
                app.p_Vy1EditField.Value = p_Vy1;
                app.p_Hy1EditField.Value = p_Hy1;
                app.ZoEditField.Value = Zo;
            end
        end

        % Button pushed function: SavesettingsButton
        function SavesettingsButtonPushed(app, event)
            [file,path] = uiputfile('*.mat','Choose a place to save your parameters');
            figure(app.GES);
            if file ~=0
                % --Main--
                type_engine = app.Type.Value;
                % masses and geometry
                
                m_v = app.mtot.Value ;
                rep = app.Massdistrib.Value ;
                W = app.WheelbaseEditField_2.Value;
                Tr = app.ReartrackwidthEditField_2.Value;
                Tf = app.FronttrackwidthEditField_2.Value;
                h_v = app.COGheightEditField_2.Value;
                % Aero
                Cz = app.CzEditField.Value;
                Cx = app.CxEditField_2.Value;
                S = app.SEditField.Value;
                Cz_rep = app.CzdistribonrearEditField.Value;
                % Pilote
                h_p = app.COGheightEditField_3.Value;
                m_p = app.MassEditField_4.Value;
                % -- Engine --
                r_rupteur = app.RPMMaxEditField_2.Value;
                if strcmp(type_engine,"Combustion")
                    r_pat = app.SlippingRPMrgimedepatinageEditField.Value;
                else
                    efficiency = app.Sefficiency;
                    eff_cont = app.ControllerefficiencyEditField.Value;
                end
                rmot = app.UITable.Data(1:end,1);
                cmot = app.UITable.Data(1:end,2);
                % -- Gearbox --
                t_pas = app.GearshifttimeEditField.Value;
                pertes = app.TransmissionlosscoeffEditField.Value;
                %Ratios
                if strcmp(type_engine,"Combustion")
                    k_p = app.PrimaryratioEditField.Value;
                    K(1) = app.stgearEditField.Value;
                    K(2) = app.ndgearEditField.Value;
                    K(3) = app.thgearEditField.Value;
                    K(4) = app.thgearEditField_2.Value;
                    K(5) = app.thgearEditField_3.Value;
                    K(6) = app.thgearEditField_4.Value;
                    k_f = app.FinalratioEditField.Value;
                else
                    k_f = app.FinalratioEditField_2.Value;
                end
                %Inertia
                Jjp = app.RimandtireEditField.Value;
                Jm = app.WheelhubandbrakediskEditField.Value;
                Ja = app.DriveshaftEditField.Value;
                Jd = app.DifferentialEditField.Value;
                Jc = app.EngineandchainEditField_2.Value;
                Jmot = app.EngineandchainEditField.Value;
                
                % -- Tires --
                D_wheel = app.WheelDiameterEditField.Value;
                coeff_roul = app.TyrerollingresistancecoefficientEditField.Value;
                Long_tire_grip = app.LongitudinaletiregripEditField.Value;
                q = app.BenchtoroadcoefficientEditField.Value;
                p_Cy1 = app.p_Cy1EditField.Value;
                p_Dy1 = app.p_Dy1EditField.Value;
                p_Ky1 = app.p_Ky1EditField.Value;
                p_Ey1 = app.p_Ey1EditField.Value;
                p_Ey3 = app.p_Ey3EditField.Value;
                p_Vy1 = app.p_Vy1EditField.Value;
                p_Hy1 = app.p_Hy1EditField.Value;
                Zo = app.ZoEditField.Value;
                
                %Computation
                m_t = m_v + m_p;
                h= (m_v*h_v + h_p*m_p)/m_t ;
                xf = W*(1-rep);
                xr = W - xf;
                J_rot = 2*(Jjp+Jm+Ja)+Jd+Jc+Jmot;
                if strcmp(type_engine,"Combustion")
                    k_max = length(K);
                    fun12 = @(X) Coupleij(app,rmot,cmot,k_p,k_f,K,1,2,X);
                    fun23 = @(X) Coupleij(app,rmot,cmot,k_p,k_f,K,2,3,X);
                    fun34 = @(X) Coupleij(app,rmot,cmot,k_p,k_f,K,3,4,X);
                    fun45 = @(X) Coupleij(app,rmot,cmot,k_p,k_f,K,4,5,X);
                    fun56 = @(X) Coupleij(app,rmot,cmot,k_p,k_f,K,5,6,X);
                    shift_RPM = [fsolve(fun12,500)/k_p/K(1)/k_f, fsolve(fun23,1000)/k_p/K(2)/k_f, fsolve(fun34,1200)/k_p/K(3)/k_f, fsolve(fun45,1200)/k_p/K(4)/k_f, fsolve(fun56,1500)/k_p/K(5)/k_f ];
                    for i=1:length(shift_RPM)
                        shift_Speed(i) = shift_RPM(i)*k_p*K(i)*k_f*2*pi/60*D_wheel/2*3.6;
                    end
                end
                % ___physical___
                g = 9.81; %gravity constant
                rho = 1.18; %air density
                % Brakes 
                Long_tire_grip_brake = (app.Deccelmax50kmhEditField.Value*m_t-1/2*rho*S*Cx*(50/3.6).^2)/(m_t*g+1/2*rho*S*Cz*(50/3.6).^2);
                x_brf = app.FronttorquedistributionEditField.Value;
                %Save
                app.param_file = fullfile(path,file);
                if strcmp(type_engine,"Combustion")
                    save(app.param_file,"type_engine","p_Ky1","p_Dy1","p_Cy1","p_Ey1","p_Ey3","p_Hy1",...
                        "p_Vy1","Zo","q","Long_tire_grip","x_brf","coeff_roul",...
                        "D_wheel","Jmot","Jc","Jd","Ja","Jm","Jjp","k_f","K","k_p",...
                        "pertes","t_pas","cmot","rmot","r_pat","r_rupteur","m_p","h_p",...
                        "Long_tire_grip_brake","Cz_rep","S","Cx","Cz","h_v","Tf","Tr",...
                        "W","rep","m_v","J_rot","xr","h","xf","m_t","g","rho","k_max","shift_Speed")
                else
                    save(app.param_file,"type_engine","p_Ky1","p_Dy1","p_Cy1","p_Ey1","p_Ey3","p_Hy1",...
                        "p_Vy1","Zo","q","Long_tire_grip","x_brf","coeff_roul",...
                        "D_wheel","Jmot","Jc","Jd","Ja","Jm","Jjp","k_f",...
                        "pertes","cmot","rmot","r_rupteur","m_p","h_p",...
                        "Long_tire_grip_brake","Cz_rep","S","Cx","Cz","h_v","Tf","Tr",...
                        "W","rep","m_v","J_rot","xr","h","xf","m_t","g","rho","efficiency",'eff_cont')
                end
            app.FilenameLabel.Text = cat(2,'file name : ',file);
            end
            
        end

        % Button pushed function: BrowseButton_2, 
        % ImportfrommatButton
        function ImportfrommatButtonPushed(app, event)
            [file,path] =  uigetfile('*.mat','Select a track file');
            figure(app.GES);
            if file ~=0
                app.FilenameLabel_2.Text = cat(2,'file name : ',file);
                app.track_file = fullfile(path,file);
                load(app.track_file,'track','Tbest_Accel','Tbest_Skidpad','Tbest_AutoX')
                app.AccelEditField.Value = Tbest_Accel;
                app.SkidpadEditField.Value = Tbest_Skidpad;
                app.AutoXEditField.Value = Tbest_AutoX;
                app.Track = track;
                app.StartSlider.Visible = 'on';
                app.EndSlider.Visible = 'on';
                app.StartSlider.Limits = [1 length(app.Track)];
                app.EndSlider.Limits = [1 length(app.Track)];
                app.StartSlider.Value = 1;
                app.EndSlider.Value = length(app.Track);
                plot(app.TrackAxe,app.Track(:,1),app.Track(:,2))
            end
        end

        % Button pushed function: ImportfromworkspaceButton
        function ImportfromworkspaceButtonPushed(app, event)
            X = evalin('base','X');
            Y = evalin('base','Y');
            if ~isempty(X) && ~isempty(Y)
                windowWidth = 25;
                polynomialOrder = 2;
                smoothX = sgolayfilt(X, polynomialOrder, windowWidth);
                smoothY = sgolayfilt(Y, polynomialOrder, windowWidth);
                app.Track = [smoothX(1:x),smoothY(1:x)];
                app.StartSlider.Visible = 'on';
                app.EndSlider.Visible = 'on';
                app.StartSlider.Limits = [1 length(app.Track)];
                app.EndSlider.Limits = [1 length(app.Track)];
                app.StartSlider.Value = 1;
                app.EndSlider.Value = length(app.Track);
                plot(app.TrackAxe,app.Track(:,1),app.Track(:,2))
            end
        end

        % Value changing function: StartSlider
        function StartSliderValueChanging(app, event)
            changingValue = round(event.Value);
            plot(app.TrackAxe,app.Track(changingValue:round(app.EndSlider.Value),1),app.Track(changingValue:round(app.EndSlider.Value),2))
        end

        % Value changing function: EndSlider
        function EndSliderValueChanging(app, event)
            changingValue = round(event.Value);
            plot(app.TrackAxe,app.Track(round(app.StartSlider.Value):changingValue,1),app.Track(round(app.StartSlider.Value):changingValue,2))
        end

        % Button pushed function: SaveTrackButton
        function SaveTrackButtonPushed(app, event)
            [file,path] = uiputfile('*.mat','Choose a place to save your parameters');
            figure(app.GES);
            if file ~=0
                app.FilenameLabel_2.Text = cat(2,'file name : ',file);
                app.track_file = fullfile(path,file);
                track = app.Track;
                Tbest_Accel = app.AccelEditField.Value;
                Tbest_Skidpad = app.SkidpadEditField.Value;
                Tbest_AutoX = app.AutoXEditField.Value;
                save(app.track_file,"track","Tbest_Accel","Tbest_Skidpad","Tbest_AutoX")
            end
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            cla(app.ResultAxe)
            if ~isempty(app.param_file) && ~isempty(app.track_file)
                
                app.ComputingLabel.Text = 'Computing...';
                app.ComputingLabel.Visible = 'on';
                
                [path,~,~] = fileparts(mfilename('fullpath'));
                assignin('base',"path",path);
                addpath(genpath(cat(2,path,'\subfunctions')))
                
                load(app.track_file,'Tbest_Accel','Tbest_Skidpad','Tbest_AutoX')
                %Accel
                value = app.Type.Value;
                if strcmp(value,"Combustion")
                    [V_acc,~,time] = Accel(75,app.param_file);
                else
                    [V_acc,~,time] = Accelelec(75,app.param_file);
                end
                app.AccelTime = time(end);
                app.AccelTimeLabel.Text = cat(2,'Acceleration Time : ',num2str(round(app.AccelTime,2)),' s');
                app.TopSpeedLabel.Text = cat(2,'Top speed : ',num2str(round(max(V_acc*3.6))),' km/h');
                app.PointsLabel.Text = cat(2, 'Points : ',num2str(round(pt_calcul('Accel',Tbest_Accel,app.AccelTime),2)));             
                
                %Skidpad
                R_skidpad = 8;
                [Gy_skidpad,V_skidpad] = findGymax(R_skidpad,app.param_file);
                app.SkidpadTime = (2*pi*R_skidpad)/V_skidpad;
                app.SkidpadTimeLabel.Text = cat(2,'Skidpad Time : ',num2str(round(app.SkidpadTime,2)),' s');
                app.GyLabel.Text = cat(2,'Gy : ',num2str(round(Gy_skidpad/9.81,1)),' g');
                app.PointsLabel_2.Text = cat(2, 'Points : ',num2str(round(pt_calcul('Skidpad',Tbest_Skidpad,app.SkidpadTime),2))); 
                
                %AutoX
                [app.VautoX,app.GxautoX,app.GyautoX,app.TautoX,app.DautoX,app.GGV] = LapTime(app.track_file,app.param_file,0);
                app.AutoXTime = app.TautoX(end);
                app.AutoXTimeLabel.Text = cat(2,'AutoX Time : ',num2str(round(app.AutoXTime,2)),' s');
                app.TopSpeedLabel_2.Text = cat(2,'Top speed : ',num2str(round(max(app.VautoX*3.6))),' km/h');
                app.PointsLabel_3.Text = cat(2, 'Points : ',num2str(round(pt_calcul('AutoX',Tbest_AutoX,app.AutoXTime),2)));
                plotGGV(app,app.GGV)     
                app.VautoX = app.VautoX*3.6;
                app.SpeedCheckBox.Visible = 'off';
                app.GlateralCheckBox.Visible = 'off';
                app.GlongitudinalCheckBox.Visible = 'off';
                app.PlottypeDropDown.Value = 'GGV';
                app.ResultsPanel.Visible = 'on';
                app.PlotSettingsPanel.Visible = 'on';
                app.ResultAxe.Visible = 'on';
                app.ComputingLabel.Text = 'Done';
                app.ExportDatatoworkspaceButton.Visible = 'on';
            else
                app.ComputingLabel.Text = 'Warning : You sould specify the parameter file and the track file';
                app.ComputingLabel.Visible = 'on';
            end
        end

        % Value changed function: PlottypeDropDown
        function PlottypeDropDownValueChanged(app, event)
            value = app.PlottypeDropDown.Value;
            cla(app.ResultAxe)
            if strcmp(value,'GGV')
                axis(app.ResultAxe,'auto')
                plotGGV(app,app.GGV)     
                app.SpeedCheckBox.Visible = 'off';
                app.GlateralCheckBox.Visible = 'off';
                app.GlongitudinalCheckBox.Visible = 'off';
            else
                app.SpeedCheckBox.Visible = 'on';
                app.GlateralCheckBox.Visible = 'on';
                app.GlongitudinalCheckBox.Visible = 'on';
                app.SpeedCheckBox.Value = 1;
                app.GlateralCheckBox.Value = 0 ;
                app.GlongitudinalCheckBox.Value = 0 ;
                if strcmp(value,'Track')
                    track_plot(app, app.Track(:,1), app.Track(:,2), app.VautoX,'Speed (km/h)',app.ResultAxe)
                elseif strcmp(value,'Time plot')
                    axis(app.ResultAxe,'auto')
                    plot(app.ResultAxe,app.TautoX,app.VautoX,'DisplayName','Speed (km/h)')
                    title(app.ResultAxe,'Time plot')
                    xlabel(app.ResultAxe,'Time (s)')
                    legend(app.ResultAxe)
                else
                    axis(app.ResultAxe,'auto')
                    plot(app.ResultAxe,app.DautoX,app.VautoX,'DisplayName','Speed (km/h)')
                    title(app.ResultAxe,'Distance plot')
                    xlabel(app.ResultAxe,'Distance (m)')
                    legend(app.ResultAxe)
                end
            end
        end

        % Value changed function: SpeedCheckBox
        function SpeedCheckBoxValueChanged(app, event)
            value = app.SpeedCheckBox.Value;
            value_DD = app.PlottypeDropDown.Value;
            if value
                if strcmp(value_DD,'Track')
                    track_plot(app, app.Track(:,1), app.Track(:,2), app.VautoX,'Speed (km/h)',app.ResultAxe)
                    app.SpeedCheckBox.Value = 1;
                    app.GlateralCheckBox.Value = 0 ;
                    app.GlongitudinalCheckBox.Value = 0 ;
                elseif strcmp(value_DD,'Time plot')
                    hold(app.ResultAxe,'on')
                    plot(app.ResultAxe,app.TautoX,app.VautoX,'DisplayName','Speed (km/h)')
                    title(app.ResultAxe,'Time plot')
                    xlabel(app.ResultAxe,'Time (s)')
                    legend(app.ResultAxe)
                else
                    hold(app.ResultAxe,'on')
                    plot(app.ResultAxe,app.DautoX,app.VautoX,'DisplayName','Speed (km/h)')
                    title(app.ResultAxe,'Distance plot')
                    xlabel(app.ResultAxe,'Distance (m)')
                    legend(app.ResultAxe)
                end
            else
                cla(app.ResultAxe)
                if strcmp(value_DD,'Time plot')
                    if app.GlateralCheckBox.Value &&  app.GlongitudinalCheckBox.Value
                        hold(app.ResultAxe,'on')
                        plot(app.ResultAxe,app.TautoX,app.GxautoX,'DisplayName','Glong (g)')
                        plot(app.ResultAxe,app.TautoX,app.GyautoX,'DisplayName','Glat (g)')
                    elseif app.GlateralCheckBox.Value &&  ~app.GlongitudinalCheckBox.Value
                        plot(app.ResultAxe,app.TautoX,app.GyautoX,'DisplayName','Glat (g)')
                    elseif ~app.GlateralCheckBox.Value &&  app.GlongitudinalCheckBox.Value
                        plot(app.ResultAxe,app.TautoX,app.GxautoX,'DisplayName','Glong (g)')
                    end
                    title(app.ResultAxe,'Time plot')
                    xlabel(app.ResultAxe,'Time (s)')
                    legend(app.ResultAxe)
                else
                    if app.GlateralCheckBox.Value &&  app.GlongitudinalCheckBox.Value
                        hold(app.ResultAxe,'on')
                        plot(app.ResultAxe,app.DautoX,app.GxautoX,'DisplayName','Glong (g)')
                        plot(app.ResultAxe,app.DautoX,app.GyautoX,'DisplayName','Glat (g)')
                    elseif app.GlateralCheckBox.Value &&  ~app.GlongitudinalCheckBox.Value
                        plot(app.ResultAxe,app.DautoX,app.GyautoX,'DisplayName','Glat (g)')
                    elseif ~app.GlateralCheckBox.Value &&  app.GlongitudinalCheckBox.Value
                        plot(app.ResultAxe,app.DautoX,app.GxautoX,'DisplayName','Glong (g)')
                    end
                    title(app.ResultAxe,'Distance plot')
                    xlabel(app.ResultAxe,'Distance (m)')
                    legend(app.ResultAxe)
                end
            end
        end

        % Value changed function: GlateralCheckBox
        function GlateralCheckBoxValueChanged(app, event)
            value = app.GlateralCheckBox.Value;
            value_DD = app.PlottypeDropDown.Value;
            if value
                if strcmp(value_DD,'Track')
                    track_plot(app, app.Track(:,1), app.Track(:,2), app.GyautoX,'Glat (g)',app.ResultAxe)
                    app.SpeedCheckBox.Value = 0;
                    app.GlateralCheckBox.Value = 1 ;
                    app.GlongitudinalCheckBox.Value = 0 ;
                elseif strcmp(value_DD,'Time plot')
                    hold(app.ResultAxe,'on')
                    plot(app.ResultAxe,app.TautoX,app.GyautoX,'DisplayName','Glat (g)')
                    title(app.ResultAxe,'Time plot')
                    xlabel(app.ResultAxe,'Time (s)')
                    legend(app.ResultAxe)
                else
                    hold(app.ResultAxe,'on')
                    plot(app.ResultAxe,app.DautoX,app.GyautoX,'DisplayName','Glat (g)')
                    title(app.ResultAxe,'Distance plot')
                    xlabel(app.ResultAxe,'Distance (m)')
                    legend(app.ResultAxe)
                end
            else
                cla(app.ResultAxe)
                if strcmp(value_DD,'Time plot')
                    if app.SpeedCheckBox.Value &&  app.GlongitudinalCheckBox.Value
                        hold(app.ResultAxe,'on')
                        plot(app.ResultAxe,app.TautoX,app.GxautoX,'DisplayName','Glong (g)')
                        plot(app.ResultAxe,app.TautoX,app.VautoX,'DisplayName','Speed (km/h)')
                    elseif app.SpeedCheckBox.Value &&  ~app.GlongitudinalCheckBox.Value
                        plot(app.ResultAxe,app.TautoX,app.VautoX,'DisplayName','Speed (km/h)')
                    elseif ~app.SpeedCheckBox.Value &&  app.GlongitudinalCheckBox.Value
                        plot(app.ResultAxe,app.TautoX,app.GxautoX,'DisplayName','Glong (g)')
                    end
                    title(app.ResultAxe,'Time plot')
                    xlabel(app.ResultAxe,'Time (s)')
                    legend(app.ResultAxe)
                else
                    if app.SpeedCheckBox.Value &&  app.GlongitudinalCheckBox.Value
                        hold(app.ResultAxe,'on')
                        plot(app.ResultAxe,app.DautoX,app.GxautoX,'DisplayName','Glong (g)')
                        plot(app.ResultAxe,app.DautoX,app.VautoX,'DisplayName','Speed (km/h)')
                    elseif app.SpeedCheckBox.Value &&  ~app.GlongitudinalCheckBox.Value
                        plot(app.ResultAxe,app.DautoX,app.VautoX,'DisplayName','Speed (km/h)')
                    elseif ~app.SpeedCheckBox.Value &&  app.GlongitudinalCheckBox.Value
                        plot(app.ResultAxe,app.DautoX,app.GxautoX,'DisplayName','Glong (g)')
                    end
                    title(app.ResultAxe,'Distance plot')
                    xlabel(app.ResultAxe,'Distance (m)')
                    legend(app.ResultAxe)
                end
            end
        end

        % Value changed function: GlongitudinalCheckBox
        function GlongitudinalCheckBoxValueChanged(app, event)
            value = app.GlongitudinalCheckBox.Value;
            value_DD = app.PlottypeDropDown.Value;
            if value
                if strcmp(value_DD,'Track')
                    track_plot(app, app.Track(:,1), app.Track(:,2), app.GxautoX,'Glong (g)',app.ResultAxe)
                    app.SpeedCheckBox.Value = 0;
                    app.GlateralCheckBox.Value = 1 ;
                    app.GlongitudinalCheckBox.Value = 0 ;
                elseif strcmp(value_DD,'Time plot')
                    hold(app.ResultAxe,'on')
                    plot(app.ResultAxe,app.TautoX,app.GxautoX,'DisplayName','Glong (g)')
                    title(app.ResultAxe,'Time plot')
                    xlabel(app.ResultAxe,'Time (s)')
                    legend(app.ResultAxe)
                else
                    hold(app.ResultAxe,'on')
                    plot(app.ResultAxe,app.DautoX,app.GxautoX,'DisplayName','Glong (g)')
                    
                    title(app.ResultAxe,'Distance plot')
                    xlabel(app.ResultAxe,'Distance (m)')
                    legend(app.ResultAxe)
                end
            else
                cla(app.ResultAxe)
                if strcmp(value_DD,'Time plot')
                    if app.SpeedCheckBox.Value &&  app.GlateralCheckBox.Value
                        hold(app.ResultAxe,'on')
                        plot(app.ResultAxe,app.TautoX,app.GyautoX,'DisplayName','Glat (g)')
                        plot(app.ResultAxe,app.TautoX,app.VautoX,'DisplayName','Speed (km/h)')
                    elseif app.SpeedCheckBox.Value &&  ~app.GlateralCheckBox.Value
                        plot(app.ResultAxe,app.TautoX,app.VautoX,'DisplayName','Speed (km/h)')
                    elseif ~app.SpeedCheckBox.Value &&  app.GlateralCheckBox.Value
                        plot(app.ResultAxe,app.TautoX,app.GyautoX,'DisplayName','Glat (g)')
                    end
                    title(app.ResultAxe,'Time plot')
                    xlabel(app.ResultAxe,'Time (s)')
                    legend(app.ResultAxe)
                else
                    if app.SpeedCheckBox.Value &&  app.GlateralCheckBox.Value
                        hold(app.ResultAxe,'on')
                        plot(app.ResultAxe,app.DautoX,app.GyautoX,'DisplayName','Glat (g)')
                        plot(app.ResultAxe,app.DautoX,app.VautoX,'DisplayName','Speed (km/h)')
                    elseif app.SpeedCheckBox.Value &&  ~app.GlateralCheckBox.Value
                        plot(app.ResultAxe,app.DautoX,app.VautoX,'DisplayName','Speed (km/h)')
                    elseif ~app.SpeedCheckBox.Value &&  app.GlateralCheckBox.Value
                        plot(app.ResultAxe,app.DautoX,app.GyautoX,'DisplayName','Glat (g)')
                    end
                    title(app.ResultAxe,'Distance plot')
                    xlabel(app.ResultAxe,'Distance (m)')
                    legend(app.ResultAxe)
                end
            end
        end

        % Value changed function: Type
        function TypeValueChanged(app, event)
            value = app.Type.Value;
            majType(app, value)
        end

        % Button pushed function: ExportDatatoworkspaceButton
        function ExportDatatoworkspaceButtonPushed(app, event)
            assignin('base',"VautoX",app.VautoX);
            assignin('base',"GGV",app.GGV);
            assignin('base',"DautoX",app.DautoX);
            assignin('base',"TautoX",app.TautoX);
            assignin('base',"GyautoX",app.GyautoX);
            assignin('base',"GxautoX",app.GxautoX);
        end

        % Button pushed function: PlotMotorEfficiencycurbButton
        function PlotMotorEfficiencycurbButtonPushed(app, event)
        cla(app.EngineAxe)
        for Z =1:app.Sefficiency.Znb
            if Z==1
                data = app.Sefficiency.Z1;
            elseif Z==2
                data = app.Sefficiency.Z2;
            elseif Z==3
                data = app.Sefficiency.Z3;
            elseif Z==4
                data = app.Sefficiency.Z4;
            elseif Z==5
                data = app.Sefficiency.Z5;
            elseif Z==6
                data = app.Sefficiency.Z6;
            end
            plot(app.EngineAxe,[data(:,1);data(1,1)],[data(:,2);data(1,2)],"DisplayName",cat(2,num2str(data(1,3)),'%'))
            hold(app.EngineAxe,'on')
        end
        legend(app.EngineAxe)
        title(app.EngineAxe,"efficiency map")
        xlabel(app.EngineAxe," Motor speed (rpm)")
        ylabel(app.EngineAxe,"Motor Torque (N.m)")
        end

        % Button pushed function: LaunchTScalculsButton
        function LaunchTScalculsButtonPushed(app, event)
            [app.VautoX2,app.GxautoX2,app.GyautoX2,app.TautoX2] = LapTime(app.track_file,app.param_file,app.VautoX(end)/3.6);
            [app.PbatautoX,Eendurance,app.c_m_autoX,app.rpm_autoX] = get_Pelec(app.VautoX,app.GxautoX,app.TautoX,app.param_file,2,app.brakeenergyrecoveryCheckBox.Value);
            [~,Eendurance2] = get_Pelec(app.VautoX2*3.6,app.GxautoX2,app.TautoX2,app.param_file,app.NumberofenduranceLapEditField.Value-2,app.brakeenergyrecoveryCheckBox.Value);
            disp(Eendurance2/3600/1000)
            disp(Eendurance/3600/1000)
            app.TSAxes.Visible = 'on';
            app.PlotDropDown.Visible = 'on';
            app.ExportDatatoworkspaceButton_2.Visible = 'on';
            app.TotalenergyusedbythebatteryLabel.Visible = 'on';
            app.TotalenergyusedbythebatteryLabel.Text = cat(2,'Total energy used by the battery : ',num2str(round((Eendurance+Eendurance2)/3600/1000,2)),' kWh');
            plot(app.TSAxes,app.TautoX,app.PbatautoX/1000)
            title(app.TSAxes,"Battery power curb")
            xlabel(app.TSAxes," Time(s)")
            ylabel(app.TSAxes,"Power (kW)")
        end

        % Button pushed function: ExportDatatoworkspaceButton_2
        function ExportDatatoworkspaceButton_2Pushed(app, event)
            assignin('base',"VautoX",app.VautoX);
            assignin('base',"GGV",app.GGV);
            assignin('base',"DautoX",app.DautoX);
            assignin('base',"TautoX",app.TautoX);
            assignin('base',"GyautoX",app.GyautoX);
            assignin('base',"c_m_autoX",app.c_m_autoX);
            assignin('base',"rpm_autoX",app.rpm_autoX);
            assignin('base',"PbatautoX",app.PbatautoX);
            assignin('base',"GxautoX",app.GxautoX);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GES = uifigure('Visible', 'off');
            app.GES.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.GES);
            app.CarsettingsTab = uitab(app.TabGroup);
            app.CarsettingsTab.Title = 'Car settings';
            app.TabGroup2 = uitabgroup(app.CarsettingsTab);
            app.MainTab = uitab(app.TabGroup2);
            app.MainTab.Title = 'Main';
            app.MassesanddimensionsPanel = uipanel(app.MainTab);
            app.MassesanddimensionsPanel.Title = 'Masses and dimensions';
            app.kgLabel_3 = uilabel(app.MassesanddimensionsPanel);
            app.kgLabel_3.Text = 'kg';
            app.MassEditField_3Label = uilabel(app.MassesanddimensionsPanel);
            app.MassEditField_3Label.Text = 'Mass';
            app.mtot = uieditfield(app.MassesanddimensionsPanel, 'numeric');
            app.mtot.Value = 210;
            app.Label_3 = uilabel(app.MassesanddimensionsPanel);
            app.Label_3.Text = '';
            app.MassdistribonrearEditField_2Label = uilabel(app.MassesanddimensionsPanel);
            app.MassdistribonrearEditField_2Label.Text = 'Mass distrib on rear';
            app.Massdistrib = uieditfield(app.MassesanddimensionsPanel, 'numeric');
            app.Massdistrib.Value = 0.5;
            app.WheelbaseEditField_2Label = uilabel(app.MassesanddimensionsPanel);
            app.WheelbaseEditField_2Label.Text = 'Wheelbase';
            app.WheelbaseEditField_2 = uieditfield(app.MassesanddimensionsPanel, 'numeric');
            app.WheelbaseEditField_2.Value = 1.575;
            app.mLabel_10 = uilabel(app.MassesanddimensionsPanel);
            app.mLabel_10.Text = 'm';
            app.mLabel_11 = uilabel(app.MassesanddimensionsPanel);
            app.mLabel_11.Text = 'm';
            app.ReartrackwidthEditField_2Label = uilabel(app.MassesanddimensionsPanel);
            app.ReartrackwidthEditField_2Label.Text = 'Rear track width';
            app.ReartrackwidthEditField_2 = uieditfield(app.MassesanddimensionsPanel, 'numeric');
            app.ReartrackwidthEditField_2.Value = 1.165;
            app.FronttrackwidthEditField_2Label = uilabel(app.MassesanddimensionsPanel);
            app.FronttrackwidthEditField_2Label.Text = 'Front track width';
            app.FronttrackwidthEditField_2 = uieditfield(app.MassesanddimensionsPanel, 'numeric');
            app.FronttrackwidthEditField_2.Value = 1.236;
            app.mLabel_12 = uilabel(app.MassesanddimensionsPanel);
            app.mLabel_12.Text = 'm';
            app.COGheightEditField_2Label = uilabel(app.MassesanddimensionsPanel);
            app.COGheightEditField_2Label.Text = 'COG height';
            app.COGheightEditField_2 = uieditfield(app.MassesanddimensionsPanel, 'numeric');
            app.COGheightEditField_2.Value = 0.3;
            app.mLabel_13 = uilabel(app.MassesanddimensionsPanel);
            app.mLabel_13.Text = 'm';
            app.AerodynamicsPanel = uipanel(app.MainTab);
            app.AerodynamicsPanel.Title = 'Aerodynamics';
            app.Label = uilabel(app.AerodynamicsPanel);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.AerodynamicsPanel);
            app.Label_2.Text = '';
            app.mLabel_14 = uilabel(app.AerodynamicsPanel);
            app.mLabel_14.Text = 'm²';
            app.SEditFieldLabel = uilabel(app.AerodynamicsPanel);
            app.SEditFieldLabel.Text = 'S';
            app.SEditField = uieditfield(app.AerodynamicsPanel, 'numeric');
            app.SEditField.Value = 1.14;
            app.CzdistribonrearEditFieldLabel = uilabel(app.AerodynamicsPanel);
            app.CzdistribonrearEditFieldLabel.Text = 'Cz distrib on rear';
            app.CzdistribonrearEditField = uieditfield(app.AerodynamicsPanel, 'numeric');
            app.CzdistribonrearEditField.Value = 0.47;
            app.Label_5 = uilabel(app.AerodynamicsPanel);
            app.Label_5.Text = '';
            app.CxEditField_2Label = uilabel(app.AerodynamicsPanel);
            app.CxEditField_2Label.Text = 'Cx';
            app.CxEditField_2 = uieditfield(app.AerodynamicsPanel, 'numeric');
            app.CxEditField_2.Value = 1.26;
            app.CzEditFieldLabel = uilabel(app.AerodynamicsPanel);
            app.CzEditFieldLabel.Text = 'Cz';
            app.CzEditField = uieditfield(app.AerodynamicsPanel, 'numeric');
            app.CzEditField.Value = 2.23;
            app.BrakesPanel_2 = uipanel(app.MainTab);
            app.BrakesPanel_2.Title = 'Brakes';
            app.ms2Label_2 = uilabel(app.BrakesPanel_2);
            app.ms2Label_2.Text = 'm.s^-2';
            app.Deccelmax50kmhEditFieldLabel = uilabel(app.BrakesPanel_2);
            app.Deccelmax50kmhEditFieldLabel.Text = 'Deccel max (50km/h)';
            app.Deccelmax50kmhEditField = uieditfield(app.BrakesPanel_2, 'numeric');
            app.Deccelmax50kmhEditField.Value = 1.6;
            app.FronttorquedistributionEditFieldLabel = uilabel(app.BrakesPanel_2);
            app.FronttorquedistributionEditFieldLabel.Text = 'Front torque distribution';
            app.FronttorquedistributionEditField = uieditfield(app.BrakesPanel_2, 'numeric');
            app.FronttorquedistributionEditField.Value = 0.7;
            app.PilotePanel = uipanel(app.MainTab);
            app.PilotePanel.Title = 'Pilote';
            app.mLabel_15 = uilabel(app.PilotePanel);
            app.mLabel_15.Text = 'm';
            app.COGheightEditField_3Label = uilabel(app.PilotePanel);
            app.COGheightEditField_3Label.Text = 'COG height';
            app.COGheightEditField_3 = uieditfield(app.PilotePanel, 'numeric');
            app.COGheightEditField_3.Value = 0.4;
            app.kgLabel_4 = uilabel(app.PilotePanel);
            app.kgLabel_4.Text = 'kg';
            app.MassEditField_4Label = uilabel(app.PilotePanel);
            app.MassEditField_4Label.Text = 'Mass';
            app.MassEditField_4 = uieditfield(app.PilotePanel, 'numeric');
            app.MassEditField_4.Value = 70;
            app.LoadsettingsButton = uibutton(app.MainTab, 'push');
            app.LoadsettingsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadsettingsButtonPushed, true);
            app.LoadsettingsButton.Text = 'Load settings';
            app.LoadexistingcarsettingsfromamatfileLabel = uilabel(app.MainTab);
            app.LoadexistingcarsettingsfromamatfileLabel.Text = 'Load existing car settings from a .mat file';
            app.SavesettingsButton = uibutton(app.MainTab, 'push');
            app.SavesettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SavesettingsButtonPushed, true);
            app.SavesettingsButton.Text = 'Save settings';
            app.SavecurrentallsettingsinamatfileLabel = uilabel(app.MainTab);
            app.SavecurrentallsettingsinamatfileLabel.Text = 'Save current all settings in a  .mat file';
            app.Type = uiswitch(app.MainTab, 'slider');
            app.Type.Items = {'Combustion', 'Electrique'};
            app.Type.ValueChangedFcn = createCallbackFcn(app, @TypeValueChanged, true);
            app.Type.Value = 'Combustion';
            app.Image = uiimage(app.MainTab);
            app.Image.ImageSource = 'logo.png';
            app.Versiondu24052020DemanderBobencasdebugLabel = uilabel(app.MainTab);
            app.Versiondu24052020DemanderBobencasdebugLabel.Text = {'Version du 24/05/2020'; 'Demander à Bob en cas de bug'};
            app.EngineTab = uitab(app.TabGroup2);
            app.EngineTab.Title = 'Engine';
            app.PlotTorquePowercurbButton = uibutton(app.EngineTab, 'push');
            app.PlotTorquePowercurbButton.ButtonPushedFcn = createCallbackFcn(app, @PlotTorquePowercurbButtonPushed, true);
            app.PlotTorquePowercurbButton.Text = 'Plot Torque-Power curb ';
            app.RPMMaxEditField_2Label = uilabel(app.EngineTab);
            app.RPMMaxEditField_2Label.Text = 'RPM Max';
            app.RPMMaxEditField_2 = uieditfield(app.EngineTab, 'numeric');
            app.RPMMaxEditField_2.Value = 13500;
            app.rpmLabel_3 = uilabel(app.EngineTab);
            app.rpmLabel_3.Text = 'rpm';
            app.SlippingRPMrgimedepatinageEditFieldLabel = uilabel(app.EngineTab);
            app.SlippingRPMrgimedepatinageEditFieldLabel.Text = 'Slipping RPM (régime de patinage)';
            app.SlippingRPMrgimedepatinageEditField = uieditfield(app.EngineTab, 'numeric');
            app.SlippingRPMrgimedepatinageEditField.Value = 9000;
            app.rpmLabel_4 = uilabel(app.EngineTab);
            app.rpmLabel_4.Text = 'rpm';
            app.UITable = uitable(app.EngineTab);
            app.UITable.ColumnName = {'RPM'; 'Torque '};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.SlippingRPMrgimedepatinageEditFieldLabel_2 = uilabel(app.EngineTab);
            app.SlippingRPMrgimedepatinageEditFieldLabel_2.Text = 'Torque values (N.m)';
            app.PlotMotorEfficiencycurbButton = uibutton(app.EngineTab, 'push');
            app.PlotMotorEfficiencycurbButton.ButtonPushedFcn = createCallbackFcn(app, @PlotMotorEfficiencycurbButtonPushed, true);
            app.PlotMotorEfficiencycurbButton.Text = 'Plot Motor Efficiency curb ';
            app.ControllerefficiencyEditFieldLabel = uilabel(app.EngineTab);
            app.ControllerefficiencyEditFieldLabel.Text = 'Controller efficiency';
            app.ControllerefficiencyEditField = uieditfield(app.EngineTab, 'numeric');
            app.EngineAxe = uiaxes(app.EngineTab);
            title(app.EngineAxe, 'Title')
            xlabel(app.EngineAxe, 'X')
            ylabel(app.EngineAxe, 'Y')
            app.GearboxTab = uitab(app.TabGroup2);
            app.GearboxTab.Title = 'Gearbox';
            app.GearshifttimeEditFieldLabel = uilabel(app.GearboxTab);
            app.GearshifttimeEditFieldLabel.Text = 'Gearshift time';
            app.GearshifttimeEditField = uieditfield(app.GearboxTab, 'numeric');
            app.GearshifttimeEditField.Value = 0.1;
            app.sLabel = uilabel(app.GearboxTab);
            app.sLabel.Text = 's';
            app.Label_6 = uilabel(app.GearboxTab);
            app.Label_6.Text = '';
            app.TransmissionlosscoeffEditField = uieditfield(app.GearboxTab, 'numeric');
            app.TransmissionlosscoeffEditField.Value = 0.9;
            app.TransmissionlosscoeffEditFieldLabel = uilabel(app.GearboxTab);
            app.TransmissionlosscoeffEditFieldLabel.Text = 'Transmission loss coeff';
            app.RatiosPanel = uipanel(app.GearboxTab);
            app.RatiosPanel.Title = 'Ratios';
            app.Label_7 = uilabel(app.RatiosPanel);
            app.Label_7.Text = '';
            app.PrimaryratioEditField = uieditfield(app.RatiosPanel, 'numeric');
            app.PrimaryratioEditFieldLabel = uilabel(app.RatiosPanel);
            app.PrimaryratioEditFieldLabel.Text = 'Primary ratio';
            app.Label_8 = uilabel(app.RatiosPanel);
            app.Label_8.Text = '';
            app.stgearEditField = uieditfield(app.RatiosPanel, 'numeric');
            app.stgearEditFieldLabel = uilabel(app.RatiosPanel);
            app.stgearEditFieldLabel.Text = '1st gear';
            app.Label_9 = uilabel(app.RatiosPanel);
            app.Label_9.Text = '';
            app.ndgearEditField = uieditfield(app.RatiosPanel, 'numeric');
            app.ndgearEditFieldLabel = uilabel(app.RatiosPanel);
            app.ndgearEditFieldLabel.Text = '2nd gear';
            app.Label_10 = uilabel(app.RatiosPanel);
            app.Label_10.Text = '';
            app.thgearEditField = uieditfield(app.RatiosPanel, 'numeric');
            app.thgearEditFieldLabel = uilabel(app.RatiosPanel);
            app.thgearEditFieldLabel.Text = '3th gear';
            app.Label_11 = uilabel(app.RatiosPanel);
            app.Label_11.Text = '';
            app.thgearEditField_2 = uieditfield(app.RatiosPanel, 'numeric');
            app.thgearEditField_2Label = uilabel(app.RatiosPanel);
            app.thgearEditField_2Label.Text = '4th gear';
            app.Label_12 = uilabel(app.RatiosPanel);
            app.Label_12.Text = '';
            app.thgearEditField_3 = uieditfield(app.RatiosPanel, 'numeric');
            app.thgearEditField_3Label = uilabel(app.RatiosPanel);
            app.thgearEditField_3Label.Text = '5th gear';
            app.thgearEditField_4 = uieditfield(app.RatiosPanel, 'numeric');
            app.thgearLabel = uilabel(app.RatiosPanel);
            app.thgearLabel.Text = '6th gear';
            app.FinalratioEditField = uieditfield(app.RatiosPanel, 'numeric');
            app.FinalratioEditFieldLabel = uilabel(app.RatiosPanel);
            app.FinalratioEditFieldLabel.Text = 'Final ratio';
            app.InertiasPanel = uipanel(app.GearboxTab);
            app.InertiasPanel.Title = 'Inertias';
            app.Label_13 = uilabel(app.InertiasPanel);
            app.Label_13.Text = '';
            app.RimandtireEditField = uieditfield(app.InertiasPanel, 'numeric');
            app.RimandtireEditFieldLabel = uilabel(app.InertiasPanel);
            app.RimandtireEditFieldLabel.Text = 'Rim and tire';
            app.Label_14 = uilabel(app.InertiasPanel);
            app.Label_14.Text = '';
            app.WheelhubandbrakediskEditField = uieditfield(app.InertiasPanel, 'numeric');
            app.WheelhubandbrakediskEditFieldLabel = uilabel(app.InertiasPanel);
            app.WheelhubandbrakediskEditFieldLabel.Text = 'Wheel hub and brake disk';
            app.Label_15 = uilabel(app.InertiasPanel);
            app.Label_15.Text = '';
            app.DifferentialEditField = uieditfield(app.InertiasPanel, 'numeric');
            app.DifferentialEditFieldLabel = uilabel(app.InertiasPanel);
            app.DifferentialEditFieldLabel.Text = 'Differential';
            app.Label_16 = uilabel(app.InertiasPanel);
            app.Label_16.Text = '';
            app.EngineandchainEditField = uieditfield(app.InertiasPanel, 'numeric');
            app.EngineandchainEditFieldLabel = uilabel(app.InertiasPanel);
            app.EngineandchainEditFieldLabel.Text = 'Engine and chain ';
            app.Label_17 = uilabel(app.InertiasPanel);
            app.Label_17.Text = '';
            app.Label_18 = uilabel(app.InertiasPanel);
            app.Label_18.Text = '';
            app.kgmLabel = uilabel(app.InertiasPanel);
            app.kgmLabel.Text = 'kg.m²';
            app.kgmLabel_2 = uilabel(app.InertiasPanel);
            app.kgmLabel_2.Text = 'kg.m²';
            app.kgmLabel_3 = uilabel(app.InertiasPanel);
            app.kgmLabel_3.Text = 'kg.m²';
            app.kgmLabel_4 = uilabel(app.InertiasPanel);
            app.kgmLabel_4.Text = 'kg.m²';
            app.kgmLabel_5 = uilabel(app.InertiasPanel);
            app.kgmLabel_5.Text = 'kg.m²';
            app.DriveshaftEditField = uieditfield(app.InertiasPanel, 'numeric');
            app.DriveshaftEditFieldLabel = uilabel(app.InertiasPanel);
            app.DriveshaftEditFieldLabel.Text = 'Drive shaft';
            app.EngineandchainEditField_2 = uieditfield(app.InertiasPanel, 'numeric');
            app.EngineandchainEditFieldLabel_2 = uilabel(app.InertiasPanel);
            app.EngineandchainEditFieldLabel_2.Text = 'Crown and support';
            app.kgmLabel_6 = uilabel(app.InertiasPanel);
            app.kgmLabel_6.Text = 'kg.m²';
            app.UITable2 = uitable(app.GearboxTab);
            app.UITable2.ColumnName = {'Gear'; 'RPM'};
            app.UITable2.RowName = {};
            app.BestRPMtoGearshiftLabel = uilabel(app.GearboxTab);
            app.BestRPMtoGearshiftLabel.Text = 'Best RPM to Gear shift';
            app.ComputeButton = uibutton(app.GearboxTab, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Text = 'Compute';
            app.FinalratioEditField_2 = uieditfield(app.GearboxTab, 'numeric');
            app.FinalratioEditField_2Label = uilabel(app.GearboxTab);
            app.FinalratioEditField_2Label.Text = 'Final ratio';
            app.GearAxe = uiaxes(app.GearboxTab);
            title(app.GearAxe, 'Title')
            xlabel(app.GearAxe, 'X')
            ylabel(app.GearAxe, 'Y')
            app.TiresTab = uitab(app.TabGroup2);
            app.TiresTab.Title = 'Tires';
            app.Label_19 = uilabel(app.TiresTab);
            app.Label_19.Text = '';
            app.TyrerollingresistancecoefficientEditField = uieditfield(app.TiresTab, 'numeric');
            app.TyrerollingresistancecoefficientEditField.Value = 0.01;
            app.TyrerollingresistancecoefficientEditFieldLabel = uilabel(app.TiresTab);
            app.TyrerollingresistancecoefficientEditFieldLabel.Text = 'Tyre rolling resistance coefficient';
            app.Label_20 = uilabel(app.TiresTab);
            app.Label_20.Text = '';
            app.LongitudinaletiregripEditField = uieditfield(app.TiresTab, 'numeric');
            app.LongitudinaletiregripEditField.Value = 1.4;
            app.LongitudinaletiregripEditFieldLabel = uilabel(app.TiresTab);
            app.LongitudinaletiregripEditFieldLabel.Text = 'Longitudinale tire grip';
            app.mLabel_16 = uilabel(app.TiresTab);
            app.mLabel_16.Text = 'm';
            app.WheelDiameterEditField = uieditfield(app.TiresTab, 'numeric');
            app.WheelDiameterEditField.Value = 0.45;
            app.WheelDiameterEditFieldLabel = uilabel(app.TiresTab);
            app.WheelDiameterEditFieldLabel.Text = 'Wheel Diameter';
            app.PlotButton = uibutton(app.TiresTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.LateralcoeffcientsLabel = uilabel(app.TiresTab);
            app.LateralcoeffcientsLabel.Text = 'Lateral coeffcients';
            app.p_Cy1EditFieldLabel = uilabel(app.TiresTab);
            app.p_Cy1EditFieldLabel.Text = 'p_Cy1';
            app.p_Cy1EditField = uieditfield(app.TiresTab, 'numeric');
            app.p_Dy1EditFieldLabel = uilabel(app.TiresTab);
            app.p_Dy1EditFieldLabel.Text = 'p_Dy1';
            app.p_Dy1EditField = uieditfield(app.TiresTab, 'numeric');
            app.p_Ky1EditFieldLabel = uilabel(app.TiresTab);
            app.p_Ky1EditFieldLabel.Text = 'p_Ky1';
            app.p_Ky1EditField = uieditfield(app.TiresTab, 'numeric');
            app.ShapefactorforCfyLabel = uilabel(app.TiresTab);
            app.ShapefactorforCfyLabel.Text = 'Shape factor for Cfy';
            app.LateralfrictionMuyLabel = uilabel(app.TiresTab);
            app.LateralfrictionMuyLabel.Text = 'Lateral friction Muy';
            app.MaximumvalueofstiffnessKfyFznomLabel = uilabel(app.TiresTab);
            app.MaximumvalueofstiffnessKfyFznomLabel.Text = {'Maximum value of stiffness'; 'Kfy/Fznom'; ''};
            app.BenchtoroadcoefficientEditField = uieditfield(app.TiresTab, 'numeric');
            app.BenchtoroadcoefficientEditField.Value = 0.7;
            app.BenchtoroadcoefficientEditField_2Label = uilabel(app.TiresTab);
            app.BenchtoroadcoefficientEditField_2Label.Text = 'Bench to road coefficient';
            app.Label_21 = uilabel(app.TiresTab);
            app.Label_21.Text = {'???'; ''};
            app.p_Ey1EditFieldLabel = uilabel(app.TiresTab);
            app.p_Ey1EditFieldLabel.Text = 'p_Ey1';
            app.p_Ey1EditField = uieditfield(app.TiresTab, 'numeric');
            app.Label_22 = uilabel(app.TiresTab);
            app.Label_22.Text = '???';
            app.p_Ey3EditFieldLabel = uilabel(app.TiresTab);
            app.p_Ey3EditFieldLabel.Text = 'p_Ey3';
            app.p_Ey3EditField = uieditfield(app.TiresTab, 'numeric');
            app.Label_23 = uilabel(app.TiresTab);
            app.Label_23.Text = {'???'; ''};
            app.p_Hy1EditFieldLabel = uilabel(app.TiresTab);
            app.p_Hy1EditFieldLabel.Text = 'p_Hy1';
            app.p_Hy1EditField = uieditfield(app.TiresTab, 'numeric');
            app.Label_24 = uilabel(app.TiresTab);
            app.Label_24.Text = '???';
            app.p_Vy1EditFieldLabel = uilabel(app.TiresTab);
            app.p_Vy1EditFieldLabel.Text = 'p_Vy1';
            app.p_Vy1EditField = uieditfield(app.TiresTab, 'numeric');
            app.Label_25 = uilabel(app.TiresTab);
            app.Label_25.Text = '???';
            app.ZoEditFieldLabel = uilabel(app.TiresTab);
            app.ZoEditFieldLabel.Text = 'Zo';
            app.ZoEditField = uieditfield(app.TiresTab, 'numeric');
            app.TireAxe = uiaxes(app.TiresTab);
            title(app.TireAxe, 'Title')
            xlabel(app.TireAxe, 'X')
            ylabel(app.TireAxe, 'Y')
            app.ImporttrackTab = uitab(app.TabGroup);
            app.ImporttrackTab.Title = 'Import track';
            app.StartSliderLabel = uilabel(app.ImporttrackTab);
            app.StartSliderLabel.Text = 'Start';
            app.StartSlider = uislider(app.ImporttrackTab);
            app.StartSlider.ValueChangingFcn = createCallbackFcn(app, @StartSliderValueChanging, true);
            app.EndSliderLabel = uilabel(app.ImporttrackTab);
            app.EndSliderLabel.Text = 'End';
            app.EndSlider = uislider(app.ImporttrackTab);
            app.EndSlider.ValueChangingFcn = createCallbackFcn(app, @EndSliderValueChanging, true);
            app.ImportfrommatButton = uibutton(app.ImporttrackTab, 'push');
            app.ImportfrommatButton.ButtonPushedFcn = createCallbackFcn(app, @ImportfrommatButtonPushed, true);
            app.ImportfrommatButton.Text = 'Import from .mat';
            app.ImportfromworkspaceButton = uibutton(app.ImporttrackTab, 'push');
            app.ImportfromworkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @ImportfromworkspaceButtonPushed, true);
            app.ImportfromworkspaceButton.Text = 'Import from workspace';
            app.SaveTrackButton = uibutton(app.ImporttrackTab, 'push');
            app.SaveTrackButton.ButtonPushedFcn = createCallbackFcn(app, @SaveTrackButtonPushed, true);
            app.SaveTrackButton.Text = 'Save Track';
            app.ImportdatafromLabel = uilabel(app.ImporttrackTab);
            app.ImportdatafromLabel.Text = 'Import data from';
            app.aprevioussessionLabel = uilabel(app.ImporttrackTab);
            app.aprevioussessionLabel.Text = 'a previous session';
            app.presentinworkspaceLabel = uilabel(app.ImporttrackTab);
            app.presentinworkspaceLabel.Text = 'present in workspace';
            app.VariableXandYmustbeLabel = uilabel(app.ImporttrackTab);
            app.VariableXandYmustbeLabel.Text = 'Variable X and Y must be';
            app.amatfileLabel = uilabel(app.ImporttrackTab);
            app.amatfileLabel.Text = 'a .mat file';
            app.SavethetrackinLabel = uilabel(app.ImporttrackTab);
            app.SavethetrackinLabel.Text = 'Save the track in';
            app.BesttimesPanel = uipanel(app.ImporttrackTab);
            app.BesttimesPanel.Title = 'Best times';
            app.AccelEditFieldLabel = uilabel(app.BesttimesPanel);
            app.AccelEditFieldLabel.Text = 'Accel';
            app.AccelEditField = uieditfield(app.BesttimesPanel, 'numeric');
            app.AccelEditField.Value = 3.8;
            app.SkidpadEditFieldLabel = uilabel(app.BesttimesPanel);
            app.SkidpadEditFieldLabel.Text = 'Skidpad';
            app.SkidpadEditField = uieditfield(app.BesttimesPanel, 'numeric');
            app.SkidpadEditField.Value = 4.75;
            app.AutoXEditFieldLabel = uilabel(app.BesttimesPanel);
            app.AutoXEditFieldLabel.Text = 'AutoX';
            app.AutoXEditField = uieditfield(app.BesttimesPanel, 'numeric');
            app.AutoXEditField.Value = 50.5;
            app.sLabel_2 = uilabel(app.BesttimesPanel);
            app.sLabel_2.Text = 's';
            app.sLabel_3 = uilabel(app.BesttimesPanel);
            app.sLabel_3.Text = 's';
            app.sLabel_4 = uilabel(app.BesttimesPanel);
            app.sLabel_4.Text = 's';
            app.TrackAxe = uiaxes(app.ImporttrackTab);
            title(app.TrackAxe, 'Title')
            xlabel(app.TrackAxe, 'X')
            ylabel(app.TrackAxe, 'Y')
            app.SimulationTab = uitab(app.TabGroup);
            app.SimulationTab.Title = 'Simulation';
            app.CarsettingsfilePanel = uipanel(app.SimulationTab);
            app.CarsettingsfilePanel.Title = 'Car settings file';
            app.BrowseButton = uibutton(app.CarsettingsfilePanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @LoadsettingsButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.FilenameLabel = uilabel(app.CarsettingsfilePanel);
            app.FilenameLabel.Text = 'File name :';
            app.TrackfilePanel = uipanel(app.SimulationTab);
            app.TrackfilePanel.Title = 'Track file';
            app.BrowseButton_2 = uibutton(app.TrackfilePanel, 'push');
            app.BrowseButton_2.ButtonPushedFcn = createCallbackFcn(app, @ImportfrommatButtonPushed, true);
            app.BrowseButton_2.Text = 'Browse';
            app.FilenameLabel_2 = uilabel(app.TrackfilePanel);
            app.FilenameLabel_2.Text = 'File name :';
            app.SimulateButton = uibutton(app.SimulationTab, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate !';
            app.ComputingLabel = uilabel(app.SimulationTab);
            app.ComputingLabel.Text = 'Computing...';
            app.ResultsPanel = uipanel(app.SimulationTab);
            app.ResultsPanel.Title = 'Results';
            app.AccelTimeLabel = uilabel(app.ResultsPanel);
            app.AccelTimeLabel.Text = 'Accel Time :';
            app.SkidpadTimeLabel = uilabel(app.ResultsPanel);
            app.SkidpadTimeLabel.Text = 'Skidpad Time :';
            app.AutoXTimeLabel = uilabel(app.ResultsPanel);
            app.AutoXTimeLabel.Text = 'AutoX Time :';
            app.GyLabel = uilabel(app.ResultsPanel);
            app.GyLabel.Text = 'Gy :';
            app.TopSpeedLabel = uilabel(app.ResultsPanel);
            app.TopSpeedLabel.Text = 'Top Speed :';
            app.TopSpeedLabel_2 = uilabel(app.ResultsPanel);
            app.TopSpeedLabel_2.Text = 'Top Speed :';
            app.PointsLabel = uilabel(app.ResultsPanel);
            app.PointsLabel.Text = 'Points :';
            app.PointsLabel_2 = uilabel(app.ResultsPanel);
            app.PointsLabel_2.Text = 'Points :';
            app.PointsLabel_3 = uilabel(app.ResultsPanel);
            app.PointsLabel_3.Text = 'Points :';
            app.PlotSettingsPanel = uipanel(app.SimulationTab);
            app.PlotSettingsPanel.Title = 'Plot Settings';
            app.PlottypeDropDownLabel = uilabel(app.PlotSettingsPanel);
            app.PlottypeDropDownLabel.Text = 'Plot type';
            app.PlottypeDropDown = uidropdown(app.PlotSettingsPanel);
            app.PlottypeDropDown.Items = {'GGV', 'Track', 'Time plot', 'Distance plot', '', '', ''};
            app.PlottypeDropDown.ValueChangedFcn = createCallbackFcn(app, @PlottypeDropDownValueChanged, true);
            app.PlottypeDropDown.Value = 'GGV';
            app.SpeedCheckBox = uicheckbox(app.PlotSettingsPanel);
            app.SpeedCheckBox.ValueChangedFcn = createCallbackFcn(app, @SpeedCheckBoxValueChanged, true);
            app.SpeedCheckBox.Text = 'Speed';
            app.GlateralCheckBox = uicheckbox(app.PlotSettingsPanel);
            app.GlateralCheckBox.ValueChangedFcn = createCallbackFcn(app, @GlateralCheckBoxValueChanged, true);
            app.GlateralCheckBox.Text = 'G lateral';
            app.GlongitudinalCheckBox = uicheckbox(app.PlotSettingsPanel);
            app.GlongitudinalCheckBox.ValueChangedFcn = createCallbackFcn(app, @GlongitudinalCheckBoxValueChanged, true);
            app.GlongitudinalCheckBox.Text = 'G longitudinal';
            app.ExportDatatoworkspaceButton = uibutton(app.SimulationTab, 'push');
            app.ExportDatatoworkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDatatoworkspaceButtonPushed, true);
            app.ExportDatatoworkspaceButton.Text = 'Export Data to workspace';
            app.ResultAxe = uiaxes(app.SimulationTab);
            title(app.ResultAxe, 'Title')
            xlabel(app.ResultAxe, 'X')
            ylabel(app.ResultAxe, 'Y')
            app.PostProcessTab = uitab(app.TabGroup);
            app.PostProcessTab.Title = 'Post Process';
            app.TabGroup3 = uitabgroup(app.PostProcessTab);
            app.TSTab = uitab(app.TabGroup3);
            app.TSTab.Title = 'TS';
            app.LaunchTScalculsButton = uibutton(app.TSTab, 'push');
            app.LaunchTScalculsButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchTScalculsButtonPushed, true);
            app.LaunchTScalculsButton.Text = 'Launch TS calculs';
            app.brakeenergyrecoveryCheckBox = uicheckbox(app.TSTab);
            app.brakeenergyrecoveryCheckBox.Text = 'brake energy recovery';
            app.NumberofenduranceLapEditFieldLabel = uilabel(app.TSTab);
            app.NumberofenduranceLapEditFieldLabel.Text = 'Number of endurance Lap';
            app.NumberofenduranceLapEditField = uieditfield(app.TSTab, 'numeric');
            app.NumberofenduranceLapEditField.Value = 24;
            app.TotalenergyusedbythebatteryLabel = uilabel(app.TSTab);
            app.TotalenergyusedbythebatteryLabel.Text = 'Total energy used by the battery :  ';
            app.PlotLabel = uilabel(app.TSTab);
            app.PlotLabel.Text = 'Plot :';
            app.PlotDropDown = uidropdown(app.TSTab);
            app.PlotDropDown.Items = {'Power curb', 'Efficiency', 'torque/rpm'};
            app.PlotDropDown.Value = 'Power curb';
            app.ExportDatatoworkspaceButton_2 = uibutton(app.TSTab, 'push');
            app.ExportDatatoworkspaceButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExportDatatoworkspaceButton_2Pushed, true);
            app.ExportDatatoworkspaceButton_2.Text = 'Export Data to workspace';
            app.TSAxes = uiaxes(app.TSTab);
            title(app.TSAxes, 'Title')
            xlabel(app.TSAxes, 'X')
            ylabel(app.TSAxes, 'Y')
            % Show the figure after all components are created
            app.GES.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GGV_Laptime

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GES)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GES)
        end
    end
end